classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Panel        matlab.ui.container.Panel
        DropDown     matlab.ui.control.DropDown
        DropDown2    matlab.ui.control.DropDown
        DropDown2_2  matlab.ui.control.DropDown
        DropDown2_3  matlab.ui.control.DropDown
        Button       matlab.ui.control.Button
        CheckBox     matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
        CheckBox_3   matlab.ui.control.CheckBox
        Button_2     matlab.ui.control.Button
    end

    
    properties (Access = private)
        objects % File objects
        objectsName % Description
        x % Description
        filePath % Description
    end
    
    methods (Access = private)
        
        function parseFile(app,filePath)
            str = fileread(filePath);
            app.objects = strsplit(str,'\r');
            for i = 1:length(app.objects)
                arr =strsplit(app.objects{i},';');
                app.objectsName{i} = arr{1};
            end
        end
        
        function drawPlot(app,index)
                arr =strsplit(app.objects{index},';');
                for i = 2:length(arr)
                    yArr{i-1}=arr{i};
                end
                for i = 1:length(yArr)
                    y(i)= str2num(yArr{i});
                end
                hold(app.UIAxes,"on");
                plot(app.UIAxes,app.x,y,'-s');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [path,file] = uigetfile({'*.txt';'*.*'});
            app.filePath = strcat(file,path);
            parseFile(app,app.filePath);
            
            %Fill dropdown items
            app.DropDown.Items = app.objectsName; 
            app.DropDown2.Items = app.objectsName;
            app.DropDown2_2.Items = app.objectsName; 
            app.DropDown2_3.Items = app.objectsName; 
            app.DropDown.ItemsData = 1:length(app.objectsName);
            app.DropDown2.ItemsData = 1:length(app.objectsName);
            app.DropDown2_2.ItemsData = 1:length(app.objectsName);
            app.DropDown2_3.ItemsData = 1:length(app.objectsName);
            
            %Get x values
            arr =strsplit(app.objects{1},';');
            for i = 2:length(arr)
                xArr{i-1} = arr{i};
            end
            for i = 1:length(xArr)
                app.x(i)=str2num(xArr{i});
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;
            if app.DropDown.Value~=1
                drawPlot(app,app.DropDown.Value);
            end
            if app.DropDown2.Value~=1
                drawPlot(app,app.DropDown2.Value);
            end
            if app.DropDown2_2.Value~=1
                drawPlot(app,app.DropDown2_2.Value);
            end
            if app.DropDown2_3.Value~=1
                drawPlot(app,app.DropDown2_3.Value);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            fid = fopen(app.filePath, 'a+');
            fprintf(fid,'\n');
            for i = 2:length(app.objects)
                arr =strsplit(app.objects{i},';');
                for j=2:length(arr)
                    localValues(j-1)=str2num(arr{j});
                end
                localMin = min(localValues);
                localMax = max(localValues);
                localMean = mean(localValues);
                if(app.CheckBox.Value==1)
                    temp =strcat({'Minimum of '},arr{1},{' is: %d\r'});
                    fprintf(fid,temp{1},localMin);
                end
                if(app.CheckBox_2.Value==1)
                    temp = strcat({'Mean of '},arr{1},{' is: %f\n'});
                    fprintf(fid,temp{1},localMean);
                end
                if(app.CheckBox_3.Value==1)
                    temp = strcat({'Maximum of '},arr{1},{' is: %d\n'});
                    fprintf(fid,temp{1},localMax);
                end
            end
            fclose('all');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.UIFigure);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'No selection'};
            app.DropDown.ItemsData = {'1'};
            app.DropDown.Value = '1';
            app.DropDown2 = uidropdown(app.Panel);
            app.DropDown2.Items = {'No selection'};
            app.DropDown2.Value = 'No selection';
            app.DropDown2_2 = uidropdown(app.Panel);
            app.DropDown2_2.Items = {'No selection'};
            app.DropDown2_2.Value = 'No selection';
            app.DropDown2_3 = uidropdown(app.Panel);
            app.DropDown2_3.Items = {'No selection'};
            app.DropDown2_3.Value = 'No selection';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Показать';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'Минимум';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = 'Среднее';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = 'Максимум';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Вычислить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end